classdef userClass < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        OpenArrayMenu            matlab.ui.container.Menu
        SaveArrayMenu            matlab.ui.container.Menu
        LoadPreviousMenu         matlab.ui.container.Menu
        UIAxes                   matlab.ui.control.UIAxes
        ListBoxLabel             matlab.ui.control.Label
        ListBox                  matlab.ui.control.ListBox
        ButtonGroup              matlab.ui.container.ButtonGroup
        GoodButton               matlab.ui.control.RadioButton
        BadButton                matlab.ui.control.RadioButton
        NotClassifiedButton      matlab.ui.control.RadioButton
        GoBackButton             matlab.ui.control.Button
        NextButton               matlab.ui.control.Button
        SPBChannelDropDownLabel  matlab.ui.control.Label
        SPBChannelDropDown       matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        imageArray % Cell array of cropped mips
        index % Index of cropped mips
        labelArray %Binary array for classification
    end
    

    methods (Access = private)

        % Menu selected function: OpenArrayMenu
        function OpenArrayMenuSelected(app, event)
            filename = uigetfile();
            load(filename, 'filterCell');
            cellArray = filterCell;
            spbChannel = app.SPBChannelDropDown.Value;
            app.imageArray = mipCrop(cellArray, str2double(spbChannel));
            imshow(app.imageArray{1}, [], 'parent', app.UIAxes);
            labelCell = cell(size(app.imageArray));
            for n = 1:size(labelCell,1)
                labelCell{n} = sprintf('Image %d *', n);
            end
            app.ListBox.Items = labelCell;
            %% Add local variables to app
            app.index = 1;
            app.labelArray = zeros(size(app.imageArray)) - 1;
        end

        % Button pushed function: GoBackButton
        function GoBackButtonPushed(app, event)
            if app.index > 1
                app.index = app.index - 1;
                app.ListBox.Value = app.ListBox.Items{app.index};
                imshow(app.imageArray{app.index}, [], 'parent', app.UIAxes);
                % update radio button position based on arrayLabel value
                if app.labelArray(app.index) == 1
                    app.GoodButton.Value = 1;
                    app.UIAxes.Title.String = 'Good';
                elseif app.labelArray(app.index) == 0
                    app.BadButton.Value = 1;
                    app.UIAxes.Title.String = 'Bad';
                else
                    app.NotClassifiedButton.Value = 1;
                    app.UIAxes.Title.String = 'Not Classified';
                end
            end
            
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if app.index < size(app.imageArray,1)
                app.index = app.index + 1;
                app.ListBox.Value = app.ListBox.Items{app.index};
                imshow(app.imageArray{app.index}, [], 'parent', app.UIAxes);
                % update radio button position based on arrayLabel value
                if app.labelArray(app.index) == 1
                    app.GoodButton.Value = 1;
                    app.UIAxes.Title.String = 'Good';
                elseif app.labelArray(app.index) == 0
                    app.BadButton.Value = 1;
                    app.UIAxes.Title.String = 'Bad';
                else
                    app.NotClassifiedButton.Value = 1;
                    app.UIAxes.Title.String = 'Not Classified';
                end
            end
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            stringCell = strsplit(value);
            app.index = str2double(stringCell{2});
            imshow(app.imageArray{app.index}, [], 'parent', app.UIAxes);
            % update radio button position based on arrayLabel value
            if app.labelArray(app.index) == 1
                app.GoodButton.Value = 1;
                app.UIAxes.Title.String = 'Good';
            elseif app.labelArray(app.index) == 0
                app.BadButton.Value = 1;
                app.UIAxes.Title.String = 'Bad';
            else
                app.NotClassifiedButton.Value = 1;
                app.UIAxes.Title.String = 'Not Classified';
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text, 'Good')
                app.labelArray(app.index) = 1;
                app.UIAxes.Title.String = 'Good';
                app.ListBox.Items{app.index} = sprintf('Image %d 1', app.index);
            elseif strcmp(selectedButton.Text, 'Bad')
                app.labelArray(app.index) = 0;
                app.UIAxes.Title.String = 'Bad';
                app.ListBox.Items{app.index} = sprintf('Image %d 0', app.index);
            else
                app.labelArray(app.index) = -1;
                app.UIAxes.Title.String = 'Not Classified';
                app.ListBox.Items{app.index} = sprintf('Image %d *', app.index);
            end
            app.ListBox.Value = app.ListBox.Items{app.index};
        end

        % Menu selected function: SaveArrayMenu
        function SaveArrayMenuSelected(app, event)
            labelCell = num2cell(app.labelArray);
            outCell(:,1) = app.imageArray;
            outCell(:,2) = labelCell;
            uisave('outCell');
        end

        % Menu selected function: LoadPreviousMenu
        function LoadPreviousMenuSelected(app, event)
            filename = uigetfile();
            load(filename, 'outCell');
            app.imageArray = outCell(:,1);
            app.labelArray = cell2mat(outCell(:,2));
            imshow(app.imageArray{1}, [], 'parent', app.UIAxes);
            labelCell = cell(size(app.imageArray));
            for n = 1:size(labelCell,1)
                if app.labelArray(n) == -1
                    labelCell{n} = sprintf('Image %d *', n);
                elseif app.labelArray(n) == 0
                    labelCell{n} = sprintf('Image %d 0', n);
                elseif app.labelArray(n) == 1
                    labelCell{n} = sprintf('Image %d 1', n);
                end
            end
            app.ListBox.Items = labelCell;
            %% Add local variables to app
            app.index = 1;
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if key == 'j'
                GoBackButtonPushed(app, event);
            elseif key == 'k'
                NextButtonPushed(app, event);
            elseif key == 'g'
                app.GoodButton.Value = 1;
                ButtonGroupSelectionChanged(app, event);
            elseif key == 'b'
                app.BadButton.Value = 1;
                ButtonGroupSelectionChanged(app, event);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.OpenArrayMenu = uimenu(app.UIFigure);
            app.OpenArrayMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenArrayMenuSelected, true);
            app.OpenArrayMenu.Text = 'Open Array';
            app.SaveArrayMenu = uimenu(app.UIFigure);
            app.SaveArrayMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveArrayMenuSelected, true);
            app.SaveArrayMenu.Text = 'Save Array';
            app.LoadPreviousMenu = uimenu(app.UIFigure);
            app.LoadPreviousMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadPreviousMenuSelected, true);
            app.LoadPreviousMenu.Text = 'Load Previous';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Not Classified')
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.GoodButton = uiradiobutton(app.ButtonGroup);
            app.GoodButton.Text = 'Good';
            app.BadButton = uiradiobutton(app.ButtonGroup);
            app.BadButton.Text = 'Bad';
            app.NotClassifiedButton = uiradiobutton(app.ButtonGroup);
            app.NotClassifiedButton.Text = 'Not Classified';
            app.NotClassifiedButton.Value = true;
            app.GoBackButton = uibutton(app.UIFigure, 'push');
            app.GoBackButton.ButtonPushedFcn = createCallbackFcn(app, @GoBackButtonPushed, true);
            app.GoBackButton.Text = 'Go Back';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.SPBChannelDropDownLabel = uilabel(app.UIFigure);
            app.SPBChannelDropDownLabel.Text = 'SPB Channel';
            app.SPBChannelDropDown = uidropdown(app.UIFigure);
            app.SPBChannelDropDown.Items = {'1', '2'};
            app.SPBChannelDropDown.Value = '2';
        end
    end

    methods (Access = public)

        % Construct app
        function app = userClass

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end